classdef traitement_image < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        AccueilTab                      matlab.ui.container.Tab
        UIAxes7                         matlab.ui.control.UIAxes
        InterfacetraitementdimageLabel  matlab.ui.control.Label
        RaliseparAfrahsalemWafaguizaniLabel  matlab.ui.control.Label
        EncadresparMMohamedKallelLabel  matlab.ui.control.Label
        GEC3Label                       matlab.ui.control.Label
        Page1Tab                        matlab.ui.container.Tab
        selectionneruneImageButton      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        hauteurdelimageEditFieldLabel   matlab.ui.control.Label
        hauteurdelimageEditField        matlab.ui.control.NumericEditField
        largeurdelimageEditFieldLabel   matlab.ui.control.Label
        largeurdelimageEditField        matlab.ui.control.NumericEditField
        TailledelimageLabel             matlab.ui.control.Label
        Page2Tab                        matlab.ui.container.Tab
        AfficheruneportiondelimageLabel  matlab.ui.control.Label
        debutdeLigneEditFieldLabel      matlab.ui.control.Label
        debutdeLigneEditField           matlab.ui.control.NumericEditField
        findeLigneLabel                 matlab.ui.control.Label
        findeLigneEditField             matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        runButton                       matlab.ui.control.Button
        debutdeColonneEditFieldLabel    matlab.ui.control.Label
        debutdeColonneEditField         matlab.ui.control.NumericEditField
        findeColonneEditFieldLabel      matlab.ui.control.Label
        findeColonneEditField           matlab.ui.control.NumericEditField
        parametresLabel                 matlab.ui.control.Label
        Page3Tab                        matlab.ui.container.Tab
        niveaudegrisEditFieldLabel      matlab.ui.control.Label
        niveaudegrisEditField           matlab.ui.control.NumericEditField
        UIAxes3                         matlab.ui.control.UIAxes
        nombredapparitionEditFieldLabel  matlab.ui.control.Label
        nombredapparitionEditField      matlab.ui.control.NumericEditField
        runButton_2                     matlab.ui.control.Button
        ScanningdelimageLabel           matlab.ui.control.Label
        Page4Tab                        matlab.ui.container.Tab
        choisirlanglederotationEditFieldLabel  matlab.ui.control.Label
        choisirlanglederotationEditField  matlab.ui.control.NumericEditField
        UIAxes4                         matlab.ui.control.UIAxes
        runButton_3                     matlab.ui.control.Button
        AppliquerunerotationsurlimagechoisieLabel  matlab.ui.control.Label
        Page5Tab                        matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        frequencedesniveauxdegrisdelimageButton  matlab.ui.control.Button
        runButton_5                     matlab.ui.control.Button
        runButton_4                     matlab.ui.control.Button
        numerodeligneLabel              matlab.ui.control.Label
        numerodeligneEditField          matlab.ui.control.NumericEditField
        numerodecolonneEditFieldLabel   matlab.ui.control.Label
        numerodecolonneEditField        matlab.ui.control.NumericEditField
        profiledecolonneLabel           matlab.ui.control.Label
        profiledeligneLabel             matlab.ui.control.Label
        HistogrammesetprofilesLabel     matlab.ui.control.Label
        Page6Tab                        matlab.ui.container.Tab
        typedebruitDropDownLabel        matlab.ui.control.Label
        typedebruitDropDown             matlab.ui.control.DropDown
        coefficientdebruitageEditFieldLabel  matlab.ui.control.Label
        coefficientdebruitageEditField  matlab.ui.control.NumericEditField
        typedefiltreDropDownLabel       matlab.ui.control.Label
        typedefiltreDropDown            matlab.ui.control.DropDown
        ajouterBruitButton              matlab.ui.control.Button
        UIAxes6                         matlab.ui.control.UIAxes
        saisirlatailledufiltreEditFieldLabel  matlab.ui.control.Label
        saisirlatailledufiltreEditField  matlab.ui.control.NumericEditField
        ajouterfiltreButton             matlab.ui.control.Button
        BruitLabel                      matlab.ui.control.Label
        FiltreLabel                     matlab.ui.control.Label
        UIAxes8                         matlab.ui.control.UIAxes
        calculerPSNRButton              matlab.ui.control.Button
        PSNREditFieldLabel              matlab.ui.control.Label
        PSNREditField                   matlab.ui.control.NumericEditField
    end


    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
         
        log = imread('C:\Users\Dell\Desktop\image.jfif');
        imshow(log, 'Parent', app.UIAxes7, 'InitialMagnification', 'fit');
        axis(app.UIAxes7, 'image');  
        axis(app.UIAxes7, 'off');    
        end

        % Button pushed function: selectionneruneImageButton
        function selectionneruneImageButtonPushed(app, event)
            
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Image Files (*.jpg, *.png, *.bmp, *.tif)'});
    if isequal(file, 0)
        disp('Aucune image sélectionnée.');
    else
        pathedelimage = fullfile(path, file);
        image = imread(pathedelimage); 
        imshow(image, 'Parent', app.UIAxes); 
        app.hauteurdelimageEditField.Value = size(image, 1); 
        app.largeurdelimageEditField.Value = size(image, 2); 
    end
    
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
     
    if isempty(app.UIAxes.Children)
        uialert(app.UIFigure, 'Veuillez d''abord sélectionner une image.', 'Aucune image chargée');
        return;
    end 
    image = app.UIAxes.Children.CData; 
    [h, l, ~] = size(image);
    debutLigne = app.debutdeLigneEditField.Value;  
    finLigne = app.findeLigneEditField.Value;      
    debutColonne = app.debutdeColonneEditField.Value;  
    finColonne = app.findeColonneEditField.Value;      
    if debutLigne < 1 || finLigne > h || debutLigne >= finLigne
    uialert(app.UIFigure, 'La valeur de ligne est invalide, entrez une autre .', 'Erreur de sélection des lignes');
    return;
    end          
    if debutColonne < 1 || finColonne > l || debutColonne >= finColonne
    uialert(app.UIFigure, 'La valeur de colonne est invalide, entrez une autre.', 'Erreur de sélection des colonnes');
    return;
    end
    bloc = image(debutLigne:finLigne, debutColonne:finColonne, :);
    imshow(bloc, 'Parent', app.UIAxes2);
            
        end

        % Button pushed function: runButton_2
        function chercherniveaudegrisButtonPushed(app, event)
        
    if isempty(app.UIAxes.Children)
        uialert(app.UIFigure, 'Veuillez d''abord sélectionner une image dans tab1.', 'Aucune image chargée');
        return;
    end
     image = app.UIAxes.Children.CData;
    if size(image, 3) == 3
        g = rgb2gray(image);  
    else
        g = image;  
    end
     ng = app.niveaudegrisEditField.Value;  
         if ng < 0 || ng > 255
             uialert(app.UIFigure, 'Le niveau de gris doit être entre 0 et 255.', 'Niveau de gris invalide');
             return;
         end
        m = sum(g(:) == ng);%m freqence de nv de gris dans imgg
        app.nombredapparitionEditField.Value = m;
        imgscannee = 255 * ones(size(g), 'uint8');
        imgscannee(g == ng) = ng;
        imshow(imgscannee, 'Parent', app.UIAxes3);  
        end

        % Button pushed function: runButton_3
        function rotationPushed(app, event)
             if isempty(app.UIAxes.Children)
      uialert(app.UIFigure, 'Veuillez d''abord sélectionner une image dans tab1.', 'Aucune image chargée');
      return;
             end
            image = app.UIAxes.Children.CData;
            angle = app.choisirlanglederotationEditField.Value;  
             if isempty(angle) || ~isnumeric(angle)
               uialert(app.UIFigure, 'Veuillez entrer un angle de rotation valide.', 'Angle invalide');
               return;
             end
             imgrota = imrotate(image, angle);
             imshow(imgrota, 'Parent', app.UIAxes4);  
        end

        % Button pushed function: 
        % frequencedesniveauxdegrisdelimageButton
        function frequencedesniveauxdegrisdelimageButtonPushed(app, event)
             if isempty(app.UIAxes.Children)
      uialert(app.UIFigure, 'Veuillez d''abord sélectionner une image dans tab1.', 'Aucune image chargée');
      return;
             end
            image = app.UIAxes.Children.CData;
            if size(image, 3) == 3
        g = rgb2gray(image);
            end
            h = imhist(g);
            bar(app.UIAxes5, h);
            title(app.UIAxes5, 'Histogramme des niveaux de gris');
            xlabel(app.UIAxes5, 'Niveau de gris');
            ylabel(app.UIAxes5, 'Nombre de pixels');
        end

        % Button pushed function: runButton_5
        function runButton_5Pushed(app, event)
            if isempty(app.UIAxes.Children)
    uialert(app.UIFigure, 'Veuillez d''abord sélectionner une image dans tab1.', 'Aucune image chargée');
    return;
            end
            image = app.UIAxes.Children.CData;
            if size(image, 3) == 3
              image = rgb2gray(image);
            end
            ligne = app.numerodeligneEditField.Value;
             if ligne < 1 || ligne > size(image, 1)
        uialert(app.UIFigure, 'Le numéro de ligne est invalide,choisissez autre', 'Erreur');
        return;
             end
              profile = image(ligne, :);
             plot(app.UIAxes5,profile);
             title(app.UIAxes5, ['Profil de la ligne ', num2str(ligne)]);
             xlabel(app.UIAxes5, 'Colonne');
             ylabel(app.UIAxes5, 'Valeur de pixel');
        end

        % Button pushed function: runButton_4
        function runButton_4Pushed(app, event)
             if isempty(app.UIAxes.Children)
    uialert(app.UIFigure, 'Veuillez d''abord sélectionner une image dans tab1.', 'Aucune image chargée');
    return;
             end
             image = app.UIAxes.Children.CData;
             if size(image, 3) == 3
        image = rgb2gray(image);
             end
              colonne = app.numerodecolonneEditField.Value;
    if colonne < 1 || colonne > size(image, 2)
        uialert(app.UIFigure, 'Le numéro de colonne est invalide,choisissez autre', 'Erreur');
        return;
    end
             profile = image(:, colonne);
    plot(app.UIAxes5, profile);
    title(app.UIAxes5, ['Profil de la colonne ', num2str(colonne)]);
    xlabel(app.UIAxes5, 'Ligne');
    ylabel(app.UIAxes5, 'Valeur de pixel');
        end

        % Button pushed function: ajouterBruitButton
        function ajouterBruitButtonPushed(app, event)
             if isempty(app.UIAxes.Children)
      uialert(app.UIFigure, 'Veuillez charger une image dans page 1 avant d''ajouter du bruit.', 'Aucune image chargée');
      return;
             end
             image = app.UIAxes.Children.CData;
              bruit = app.typedebruitDropDown.Value; 
              co = app.coefficientdebruitageEditField.Value; 
             if co < 0 || co > 1
     uialert(app.UIFigure, 'Le coefficient de bruitage doit être entre 0 et 1.', 'Valeur de bruit invalide');
     return;
             end
              switch bruit
        case 'Salt&pepper'
            i = imnoise(image, 'salt & pepper', co);
        case 'Gaussian'
            i = imnoise(image, 'gaussian', 0, co);
        case 'Speckle'
           i = imnoise(image, 'speckle', co);
        otherwise
            uialert(app.UIFigure, 'Type de bruit non reconnu.', 'Erreur');
            return;
              end
    imshow(i, 'Parent', app.UIAxes6);
        end

        % Button pushed function: ajouterfiltreButton
        function ajouterfiltreButtonPushed(app, event)
            
             tailleFiltre = app.saisirlatailledufiltreEditField.Value;
              hauteurImage = app.hauteurdelimageEditField.Value;
              largeurImage = app.largeurdelimageEditField.Value;
               if isempty(app.UIAxes6.Children)
       uialert(app.UIFigure, 'Aucune image n''a été trouvée.', 'Erreur');
       return;
               end
              ImageSelectionnee = app.UIAxes6.Children.CData;
             if isempty(ImageSelectionnee)
    uialert(app.UIFigure, 'Veuillez sélectionner une image dans axe 6.', 'Erreur');
    return;
             end
            if tailleFiltre > min(hauteurImage, largeurImage)
    uialert(app.UIFigure, 'La taille du filtre est trop grande pour l''image.', 'Erreur');
    return;
            elseif mod(tailleFiltre, 2) == 0
    uialert(app.UIFigure, 'La taille du filtre doit être impaire (ex : 3, 5, 7, etc.)', 'Erreur');
    return;
            end
               switch app.typedefiltreDropDown.Value
     case 'Medium'
         if ndims(ImageSelectionnee) == 3
             imageFiltree(:,:,1) = medfilt2(ImageSelectionnee(:,:,1), [tailleFiltre tailleFiltre]);
             imageFiltree(:,:,2) = medfilt2(ImageSelectionnee(:,:,2), [tailleFiltre tailleFiltre]);
             imageFiltree(:,:,3) = medfilt2(ImageSelectionnee(:,:,3), [tailleFiltre tailleFiltre]);
         else
            imageFiltree = medfilt2(ImageSelectionnee, [tailleFiltre tailleFiltre]);
        end
    case 'Moyen'
        h = fspecial('average', [tailleFiltre tailleFiltre]);
        imageFiltree = imfilter(ImageSelectionnee, h);
    case 'Laplacien'
        h = fspecial('laplacian');
        imageFiltree = imfilter(ImageSelectionnee, h);
    otherwise
        uialert(app.UIFigure, 'Type de filtre inconnu.', 'Erreur');
        return;
               end
           
            imshow(imageFiltree, 'Parent', app.UIAxes8);
            
        end

        % Button pushed function: calculerPSNRButton
        function calculerPSNRButtonPushed(app, event)
            
    imgOriginale = getimage(app.UIAxes);  
       
    imgFiltree = getimage(app.UIAxes8);  
    
    
    mse = immse(imgFiltree, imgOriginale);
    
    psnrValue = 10 * log10(255^2 / mse);
    
    app.PSNREditField.Value = psnrValue;
        end

        % Callback function
        function calculerPSNRButton_2Pushed(app, event)
             
            imgOriginale = getimage(app.UIAxes);  
            
            imgBruitee = getimage(app.UIAxes6);  
            
            mseValue = immse(imgBruitee, imgOriginale);
            
            psnrValue = 10 * log10(255^2 / mseValue);
            
           
            app.PSNREditField_2.Value = num2str(psnrValue);  
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AccueilTab = uitab(app.TabGroup);
            app.AccueilTab.Title = 'Accueil';
            app.AccueilTab.Units = 'pixels';
            app.UIAxes7 = uiaxes(app.AccueilTab);
            app.InterfacetraitementdimageLabel = uilabel(app.AccueilTab);
            app.InterfacetraitementdimageLabel.Text = 'Interface traitement d''image ';
            app.RaliseparAfrahsalemWafaguizaniLabel = uilabel(app.AccueilTab);
            app.RaliseparAfrahsalemWafaguizaniLabel.Text = 'Réalisée par : Afrah salem & Wafa guizani';
            app.EncadresparMMohamedKallelLabel = uilabel(app.AccueilTab);
            app.EncadresparMMohamedKallelLabel.Text = 'Encadrées par : M.Mohamed Kallel ';
            app.GEC3Label = uilabel(app.AccueilTab);
            app.GEC3Label.Text = '2GEC3';
            app.Page1Tab = uitab(app.TabGroup);
            app.Page1Tab.Title = 'Page1';
            app.Page1Tab.Units = 'pixels';
            app.selectionneruneImageButton = uibutton(app.Page1Tab, 'push');
            app.selectionneruneImageButton.ButtonPushedFcn = createCallbackFcn(app, @selectionneruneImageButtonPushed, true);
            app.selectionneruneImageButton.Text = 'selectionner une Image';
            app.UIAxes = uiaxes(app.Page1Tab);
            title(app.UIAxes, 'image selectionnee');
            app.hauteurdelimageEditFieldLabel = uilabel(app.Page1Tab);
            app.hauteurdelimageEditFieldLabel.Text = 'hauteur de l''image';
            app.hauteurdelimageEditField = uieditfield(app.Page1Tab, 'numeric');
            app.largeurdelimageEditFieldLabel = uilabel(app.Page1Tab);
            app.largeurdelimageEditFieldLabel.Text = 'largeur de l''image';
            app.largeurdelimageEditField = uieditfield(app.Page1Tab, 'numeric');
            app.TailledelimageLabel = uilabel(app.Page1Tab);
            app.TailledelimageLabel.Text = 'Taille de l''image ';
            app.Page2Tab = uitab(app.TabGroup);
            app.Page2Tab.Title = 'Page2';
            app.Page2Tab.Units = 'pixels';
            app.AfficheruneportiondelimageLabel = uilabel(app.Page2Tab);
            app.AfficheruneportiondelimageLabel.Text = 'Afficher une portion de l''image ';
            app.debutdeLigneEditFieldLabel = uilabel(app.Page2Tab);
            app.debutdeLigneEditFieldLabel.Text = 'debut de Ligne';
            app.debutdeLigneEditField = uieditfield(app.Page2Tab, 'numeric');
            app.findeLigneLabel = uilabel(app.Page2Tab);
            app.findeLigneLabel.Text = 'fin de Ligne';
            app.findeLigneEditField = uieditfield(app.Page2Tab, 'numeric');
            app.UIAxes2 = uiaxes(app.Page2Tab);
            title(app.UIAxes2, 'portion de l''image');
            app.runButton = uibutton(app.Page2Tab, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.debutdeColonneEditFieldLabel = uilabel(app.Page2Tab);
            app.debutdeColonneEditFieldLabel.Text = 'debut de Colonne';
            app.debutdeColonneEditField = uieditfield(app.Page2Tab, 'numeric');
            app.findeColonneEditFieldLabel = uilabel(app.Page2Tab);
            app.findeColonneEditFieldLabel.Text = 'fin de Colonne';
            app.findeColonneEditField = uieditfield(app.Page2Tab, 'numeric');
            app.parametresLabel = uilabel(app.Page2Tab);
            app.parametresLabel.Text = 'parametres :';
            app.Page3Tab = uitab(app.TabGroup);
            app.Page3Tab.Title = 'Page3';
            app.Page3Tab.Units = 'pixels';
            app.niveaudegrisEditFieldLabel = uilabel(app.Page3Tab);
            app.niveaudegrisEditFieldLabel.Text = 'niveau de gris';
            app.niveaudegrisEditField = uieditfield(app.Page3Tab, 'numeric');
            app.UIAxes3 = uiaxes(app.Page3Tab);
            title(app.UIAxes3, 'image scanne');
            app.nombredapparitionEditFieldLabel = uilabel(app.Page3Tab);
            app.nombredapparitionEditFieldLabel.Text = 'nombre d''apparition';
            app.nombredapparitionEditField = uieditfield(app.Page3Tab, 'numeric');
            app.runButton_2 = uibutton(app.Page3Tab, 'push');
            app.runButton_2.ButtonPushedFcn = createCallbackFcn(app, @chercherniveaudegrisButtonPushed, true);
            app.runButton_2.Text = 'run';
            app.ScanningdelimageLabel = uilabel(app.Page3Tab);
            app.ScanningdelimageLabel.Text = 'Scanning de l''image ';
            app.Page4Tab = uitab(app.TabGroup);
            app.Page4Tab.Title = 'Page4';
            app.Page4Tab.Units = 'pixels';
            app.choisirlanglederotationEditFieldLabel = uilabel(app.Page4Tab);
            app.choisirlanglederotationEditFieldLabel.Text = 'choisir l''angle de rotation';
            app.choisirlanglederotationEditField = uieditfield(app.Page4Tab, 'numeric');
            app.UIAxes4 = uiaxes(app.Page4Tab);
            title(app.UIAxes4, 'image apres rotation');
            app.runButton_3 = uibutton(app.Page4Tab, 'push');
            app.runButton_3.ButtonPushedFcn = createCallbackFcn(app, @rotationPushed, true);
            app.runButton_3.Text = 'run';
            app.AppliquerunerotationsurlimagechoisieLabel = uilabel(app.Page4Tab);
            app.AppliquerunerotationsurlimagechoisieLabel.Text = 'Appliquer une rotation sur l''image choisie ';
            app.Page5Tab = uitab(app.TabGroup);
            app.Page5Tab.Title = 'Page5';
            app.Page5Tab.Units = 'pixels';
            app.UIAxes5 = uiaxes(app.Page5Tab);
            title(app.UIAxes5, 'histogramme d''apparition');
            xlabel(app.UIAxes5, 'niveau de gris');
            ylabel(app.UIAxes5, 'frequence');
            app.frequencedesniveauxdegrisdelimageButton = uibutton(app.Page5Tab, 'push');
            app.frequencedesniveauxdegrisdelimageButton.ButtonPushedFcn = createCallbackFcn(app, @frequencedesniveauxdegrisdelimageButtonPushed, true);
            app.frequencedesniveauxdegrisdelimageButton.Text = ' frequence des niveaux de gris de l''image';
            app.runButton_5 = uibutton(app.Page5Tab, 'push');
            app.runButton_5.ButtonPushedFcn = createCallbackFcn(app, @runButton_5Pushed, true);
            app.runButton_5.Text = 'run';
            app.runButton_4 = uibutton(app.Page5Tab, 'push');
            app.runButton_4.ButtonPushedFcn = createCallbackFcn(app, @runButton_4Pushed, true);
            app.runButton_4.Text = 'run';
            app.numerodeligneLabel = uilabel(app.Page5Tab);
            app.numerodeligneLabel.Text = 'numero de ligne';
            app.numerodeligneEditField = uieditfield(app.Page5Tab, 'numeric');
            app.numerodecolonneEditFieldLabel = uilabel(app.Page5Tab);
            app.numerodecolonneEditFieldLabel.Text = 'numero de colonne';
            app.numerodecolonneEditField = uieditfield(app.Page5Tab, 'numeric');
            app.profiledecolonneLabel = uilabel(app.Page5Tab);
            app.profiledecolonneLabel.Text = 'profile de colonne :';
            app.profiledeligneLabel = uilabel(app.Page5Tab);
            app.profiledeligneLabel.Text = 'profile de ligne :';
            app.HistogrammesetprofilesLabel = uilabel(app.Page5Tab);
            app.HistogrammesetprofilesLabel.Text = 'Histogrammes et profiles';
            app.Page6Tab = uitab(app.TabGroup);
            app.Page6Tab.Title = 'Page6';
            app.Page6Tab.Units = 'pixels';
            app.typedebruitDropDownLabel = uilabel(app.Page6Tab);
            app.typedebruitDropDownLabel.Text = 'type de bruit ';
            app.typedebruitDropDown = uidropdown(app.Page6Tab);
            app.typedebruitDropDown.Items = {'Salt&pepper', 'Speckle', 'Gaussian'};
            app.typedebruitDropDown.Value = 'Salt&pepper';
            app.coefficientdebruitageEditFieldLabel = uilabel(app.Page6Tab);
            app.coefficientdebruitageEditFieldLabel.Text = 'coefficient de bruitage';
            app.coefficientdebruitageEditField = uieditfield(app.Page6Tab, 'numeric');
            app.typedefiltreDropDownLabel = uilabel(app.Page6Tab);
            app.typedefiltreDropDownLabel.Text = 'type de filtre';
            app.typedefiltreDropDown = uidropdown(app.Page6Tab);
            app.typedefiltreDropDown.Items = {'Medium', 'Moyen', 'Laplacien'};
            app.typedefiltreDropDown.Value = 'Medium';
            app.ajouterBruitButton = uibutton(app.Page6Tab, 'push');
            app.ajouterBruitButton.ButtonPushedFcn = createCallbackFcn(app, @ajouterBruitButtonPushed, true);
            app.ajouterBruitButton.Text = ' ajouter Bruit';
            app.UIAxes6 = uiaxes(app.Page6Tab);
            title(app.UIAxes6, 'bruit');
            app.saisirlatailledufiltreEditFieldLabel = uilabel(app.Page6Tab);
            app.saisirlatailledufiltreEditFieldLabel.Text = 'saisir la taille du filtre';
            app.saisirlatailledufiltreEditField = uieditfield(app.Page6Tab, 'numeric');
            app.ajouterfiltreButton = uibutton(app.Page6Tab, 'push');
            app.ajouterfiltreButton.ButtonPushedFcn = createCallbackFcn(app, @ajouterfiltreButtonPushed, true);
            app.ajouterfiltreButton.Text = ' ajouter filtre';
            app.BruitLabel = uilabel(app.Page6Tab);
            app.BruitLabel.Text = 'Bruit :';
            app.FiltreLabel = uilabel(app.Page6Tab);
            app.FiltreLabel.Text = 'Filtre :';
            app.UIAxes8 = uiaxes(app.Page6Tab);
            title(app.UIAxes8, 'filtre');
            app.calculerPSNRButton = uibutton(app.Page6Tab, 'push');
            app.calculerPSNRButton.ButtonPushedFcn = createCallbackFcn(app, @calculerPSNRButtonPushed, true);
            app.calculerPSNRButton.Text = 'calculer PSNR';
            app.PSNREditFieldLabel = uilabel(app.Page6Tab);
            app.PSNREditFieldLabel.Text = 'PSNR';
            app.PSNREditField = uieditfield(app.Page6Tab, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = traitement_image()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end